classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        MedidaDropDown_7                matlab.ui.control.DropDown
        MedidaDropDown_7Label           matlab.ui.control.Label
        INGRESEMEDIDADEaEditField       matlab.ui.control.NumericEditField
        INGRESEMEDIDADEaEditFieldLabel  matlab.ui.control.Label
        CalcularButton_2                matlab.ui.control.Button
        CALCULARTRABAJOREQUERIDOPARAELARREGLODECARGASLabel  matlab.ui.control.Label
        MedidaDropDown_6                matlab.ui.control.DropDown
        MedidaDropDown_6Label           matlab.ui.control.Label
        ValorQ3EditField_2              matlab.ui.control.NumericEditField
        ValorQ3EditField_2Label         matlab.ui.control.Label
        MedidaDropDown_5                matlab.ui.control.DropDown
        MedidaDropDown_5Label           matlab.ui.control.Label
        ValorQ2EditField_2              matlab.ui.control.NumericEditField
        ValorQ2EditField_2Label         matlab.ui.control.Label
        MedidaDropDown_4                matlab.ui.control.DropDown
        MedidaDropDown_4Label           matlab.ui.control.Label
        ValorQ1EditField_2              matlab.ui.control.NumericEditField
        ValorQ1EditField_2Label         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        errorLabelP                     matlab.ui.control.Label
        INGRESEALTURADEPcmEditField     matlab.ui.control.NumericEditField
        INGRESEALTURADEPcmEditFieldLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        CALCULARMAGNITUDCAMPOELCTRICOLabel  matlab.ui.control.Label
        MedidaDropDown_3                matlab.ui.control.DropDown
        MedidaDropDown_3Label           matlab.ui.control.Label
        ValorQ3EditField                matlab.ui.control.NumericEditField
        ValorQ3EditFieldLabel           matlab.ui.control.Label
        MedidaDropDown_2                matlab.ui.control.DropDown
        MedidaDropDown_2Label           matlab.ui.control.Label
        ValorQ2EditField                matlab.ui.control.NumericEditField
        ValorQ2EditFieldLabel           matlab.ui.control.Label
        MedidaDropDown                  matlab.ui.control.DropDown
        MedidaDropDownLabel             matlab.ui.control.Label
        ValorQ1EditField                matlab.ui.control.NumericEditField
        ValorQ1EditFieldLabel           matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aTriangulo = 20
        camposP
        puntosP
        ke = 9*10^9;
        trabajosW
        ladosA
    end
    
    methods (Access = private)
        
        function[q] = calcularMedida(app,q,medida)
            switch(medida)
                case("C")
                    q = q*1;
                case("μC")
                    q = q * 1*10^-6;
                case("nC")
                    q = q * 1*10^-9;
            end
        end
        function a = calcularMedidaA(app,a,medida)
            switch(medida)
                case("m")
                    a = a * 1;
                case("cm")
                    a = a * 1*10^-2;
                case("mm")
                    a = a* 1*10^-3;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % El triangulo tiene lado a de 20 centimetros
            p = app.INGRESEALTURADEPcmEditField.Value;
            alt = (3^(1/2))*app.aTriangulo/2;
            if(p > alt)
                app.errorLabelP.Text = sprintf('INGRESE UN VALOR MENOR A %.2fCM',alt);
                return;
            end
            q1 = app.ValorQ1EditField.Value;
            medidaQ1 = app.MedidaDropDown.Value;
            q1 = app.calcularMedida(q1,medidaQ1);
            q2 = app.ValorQ2EditField.Value;
            medidaQ2 = app.MedidaDropDown_2.Value;
            q2 = app.calcularMedida(q2,medidaQ2);
            q3 = app.ValorQ3EditField.Value;
            medidaQ3 = app.MedidaDropDown_3.Value;
            q3 = app.calcularMedida(q3,medidaQ3);
            Eq1 = app.ke*4*q1/(app.aTriangulo^2+4*p^2);
            Eq2 = app.ke*4*q2/(app.aTriangulo^2+4*p^2);
            Eq3 = app.ke*q3/(((3^(1/2)*app.aTriangulo/2)-p)^2);
            Ep = Eq1 + Eq2 + Eq3;
            app.camposP = [app.camposP,Ep];
            app.puntosP = [app.puntosP,p];
            app.errorLabelP.Text = '';
            plot(app.UIAxes,app.puntosP,app.camposP)
  
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            a = app.INGRESEMEDIDADEaEditField.Value;
            medidaA = app.MedidaDropDown_7.Value;
            a = app.calcularMedidaA(a,medidaA);
            q1 = app.ValorQ1EditField_2.Value;
            medidaQ1 = app.MedidaDropDown_4.Value;
            q1 = app.calcularMedida(q1,medidaQ1);
            q2 = app.ValorQ2EditField_2.Value;
            medidaQ2 = app.MedidaDropDown_5.Value;
            q2 = app.calcularMedida(q2,medidaQ2);
            q3 = app.ValorQ3EditField_2.Value;
            medidaQ3 = app.MedidaDropDown_6.Value;
            q3 = app.calcularMedida(q3,medidaQ3);
            wt = app.ke*(q1*q2+q1*q3+q3*q2)/a;
            app.trabajosW = [app.trabajosW, wt];
            app.ladosA = [app.ladosA, a];
            plot(app.UIAxes_2,app.ladosA,app.trabajosW)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CAMPO ELECTRICO VS P')
            xlabel(app.UIAxes, 'PUNTO P (cm)')
            ylabel(app.UIAxes, 'Campo Eléctrico (N/C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Trabajo vs lado del triangulo (a)')
            xlabel(app.UIAxes_2, 'Lado del triangulo (a)')
            ylabel(app.UIAxes_2, 'Trabajo W(J)')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.ValorQ1EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ1EditFieldLabel.Text = 'Valor Q1';
            app.ValorQ1EditField = uieditfield(app.Panel_2, 'numeric');
            app.MedidaDropDownLabel = uilabel(app.Panel_2);
            app.MedidaDropDownLabel.Text = 'Medida';
            app.MedidaDropDown = uidropdown(app.Panel_2);
            app.MedidaDropDown.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown.Value = 'C';
            app.ValorQ2EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ2EditFieldLabel.Text = 'Valor Q2';
            app.ValorQ2EditField = uieditfield(app.Panel_2, 'numeric');
            app.ValorQ2EditField.Limits = [-Inf 0];
            app.MedidaDropDown_2Label = uilabel(app.Panel_2);
            app.MedidaDropDown_2Label.Text = 'Medida';
            app.MedidaDropDown_2 = uidropdown(app.Panel_2);
            app.MedidaDropDown_2.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_2.Value = 'C';
            app.ValorQ3EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ3EditFieldLabel.Text = 'Valor Q3';
            app.ValorQ3EditField = uieditfield(app.Panel_2, 'numeric');
            app.MedidaDropDown_3Label = uilabel(app.Panel_2);
            app.MedidaDropDown_3Label.Text = 'Medida';
            app.MedidaDropDown_3 = uidropdown(app.Panel_2);
            app.MedidaDropDown_3.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_3.Value = 'C';
            app.CALCULARMAGNITUDCAMPOELCTRICOLabel = uilabel(app.Panel_2);
            app.CALCULARMAGNITUDCAMPOELCTRICOLabel.Text = 'CALCULAR MAGNITUD CAMPO ELÉCTRICO';
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.INGRESEALTURADEPcmEditFieldLabel = uilabel(app.Panel_2);
            app.INGRESEALTURADEPcmEditFieldLabel.Text = 'INGRESE ALTURA DE P (cm)';
            app.INGRESEALTURADEPcmEditField = uieditfield(app.Panel_2, 'numeric');
            app.INGRESEALTURADEPcmEditField.Limits = [0 Inf];
            app.errorLabelP = uilabel(app.Panel_2);
            app.errorLabelP.Text = '';
            app.Panel_3 = uipanel(app.Panel);
            app.ValorQ1EditField_2Label = uilabel(app.Panel_3);
            app.ValorQ1EditField_2Label.Text = 'Valor Q1';
            app.ValorQ1EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.MedidaDropDown_4Label = uilabel(app.Panel_3);
            app.MedidaDropDown_4Label.Text = 'Medida';
            app.MedidaDropDown_4 = uidropdown(app.Panel_3);
            app.MedidaDropDown_4.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_4.Value = 'C';
            app.ValorQ2EditField_2Label = uilabel(app.Panel_3);
            app.ValorQ2EditField_2Label.Text = 'Valor Q2';
            app.ValorQ2EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.ValorQ2EditField_2.Limits = [-Inf 0];
            app.MedidaDropDown_5Label = uilabel(app.Panel_3);
            app.MedidaDropDown_5Label.Text = 'Medida';
            app.MedidaDropDown_5 = uidropdown(app.Panel_3);
            app.MedidaDropDown_5.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_5.Value = 'C';
            app.ValorQ3EditField_2Label = uilabel(app.Panel_3);
            app.ValorQ3EditField_2Label.Text = 'Valor Q3';
            app.ValorQ3EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.MedidaDropDown_6Label = uilabel(app.Panel_3);
            app.MedidaDropDown_6Label.Text = 'Medida';
            app.MedidaDropDown_6 = uidropdown(app.Panel_3);
            app.MedidaDropDown_6.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_6.Value = 'C';
            app.CALCULARTRABAJOREQUERIDOPARAELARREGLODECARGASLabel = uilabel(app.Panel_3);
            app.CALCULARTRABAJOREQUERIDOPARAELARREGLODECARGASLabel.Text = 'CALCULAR TRABAJO REQUERIDO PARA EL ARREGLO DE CARGAS';
            app.CalcularButton_2 = uibutton(app.Panel_3, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.INGRESEMEDIDADEaEditFieldLabel = uilabel(app.Panel_3);
            app.INGRESEMEDIDADEaEditFieldLabel.Text = 'INGRESE MEDIDA DE a';
            app.INGRESEMEDIDADEaEditField = uieditfield(app.Panel_3, 'numeric');
            app.INGRESEMEDIDADEaEditField.Limits = [0 Inf];
            app.MedidaDropDown_7Label = uilabel(app.Panel_3);
            app.MedidaDropDown_7Label.Text = 'Medida';
            app.MedidaDropDown_7 = uidropdown(app.Panel_3);
            app.MedidaDropDown_7.Items = {'m', 'cm', 'mm'};
            app.MedidaDropDown_7.Value = 'm';
            app.Image = uiimage(app.Panel_3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'Captura de pantalla 2025-02-14 050215.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end