classdef graficarP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        errorLabel                     matlab.ui.control.Label
        CALCULARCAMPOButton            matlab.ui.control.Button
        INGRESECOORDENADADEPEditField  matlab.ui.control.NumericEditField
        INGRESECOORDENADADEPEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        q1p;
        q2p
        q3p
        ladoA
        alt
        puntosP 
        camposP 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, q1, q2, q3, a)

            app.q1p = q1;
            app.q2p = q2;
            app.q3p = q3;
            app.ladoA = a;
            app.alt = (3^(1/2))*a/2;
        end

        % Button pushed function: CALCULARCAMPOButton
        function CALCULARCAMPOButtonPushed(app, event)
            p = app.INGRESECOORDENADADEPEditField.Value;
            ke = 9*10^9;
            if(p > app.alt)
                app.errorLabelP.Text = sprintf('INGRESE UN VALOR MENOR A %.2fCM',app.alt);
                return;
            end
            Eq1 = ke*4*app.q1p/(app.ladoA^2+4*p^2);
            Eq2 = ke*4*app.q2p/(app.ladoA^2+4*p^2);
            Eq3 = ke*app.q3p/(((3^(1/2)*app.ladoA/2)-p)^2);
            Ep = Eq1 + Eq2 + Eq3;
            app.camposP = [app.camposP,Ep];
            app.puntosP = [app.puntosP,p];
            app.errorLabel.Text = '';
            plot(app.UIAxes,app.camposP,app.puntosP)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'E vs P')
            xlabel(app.UIAxes, 'Altura P')
            ylabel(app.UIAxes, 'Campo Eléctrico')
            zlabel(app.UIAxes, 'Z')
            app.INGRESECOORDENADADEPEditFieldLabel = uilabel(app.UIFigure);
            app.INGRESECOORDENADADEPEditFieldLabel.Text = 'INGRESE COORDENADA DE P';
            app.INGRESECOORDENADADEPEditField = uieditfield(app.UIFigure, 'numeric');
            app.INGRESECOORDENADADEPEditField.Limits = [0 Inf];
            app.CALCULARCAMPOButton = uibutton(app.UIFigure, 'push');
            app.CALCULARCAMPOButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARCAMPOButtonPushed, true);
            app.CALCULARCAMPOButton.Text = 'CALCULAR CAMPO';
            app.errorLabel = uilabel(app.UIFigure);
            app.errorLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficarP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end